classdef eeprom_cali_load < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ResetButton                    matlab.ui.control.Button
        Lamp_verify                    matlab.ui.control.Lamp
        Lamp_4Label                    matlab.ui.control.Label
        Lamp_copy                      matlab.ui.control.Lamp
        Label_2                        matlab.ui.control.Label
        Lamp_cali                      matlab.ui.control.Lamp
        Lamp_2Label                    matlab.ui.control.Label
        Lamp_eep                       matlab.ui.control.Lamp
        Label                          matlab.ui.control.Label
        InfoText_log                   matlab.ui.control.TextArea
        LogLabel                       matlab.ui.control.Label
        VerifyButton                   matlab.ui.control.Button
        CalibrationtoEEPROMcopyButton  matlab.ui.control.Button
        TabGroup                       matlab.ui.container.TabGroup
        LoadedTab                      matlab.ui.container.Tab
        CalibrationfilePanel           matlab.ui.container.Panel
        UITable_cali                   matlab.ui.control.Table
        InfoText_cali                  matlab.ui.control.TextArea
        InfoTextAreaLabel              matlab.ui.control.Label
        EEPROMFilePanel                matlab.ui.container.Panel
        InfoText_eep                   matlab.ui.control.TextArea
        InfoTextArea_2Label            matlab.ui.control.Label
        UITable_eep                    matlab.ui.control.Table
        GraphTab                       matlab.ui.container.Tab
        RefreshgraphsButton            matlab.ui.control.Button
        UIAxes_delta                   matlab.ui.control.UIAxes
        UIAxes_cali                    matlab.ui.control.UIAxes
        LoadCalibrationfileButton      matlab.ui.control.Button
        LoadEEPROMfileButton           matlab.ui.control.Button
    end


    properties (Access = private)
        
        version = 'v1.03'; 
        
        eep_page_map = containers.Map('KeyType','uint32','ValueType','uint16'); % eeprom data index to page map
        eep_data;
        eep_load_success = 0;

        eep_len = 74;
        eep_idx_pagenum_start = 7; %
        eep_idx_data_start = 9;
        eep_page_len = 64;

        eep_idx_pagenum_end; %
        eep_pagenum_max = uint16 (0x0200);

        eep_page_cali_head = 9;
        eep_page_cali_data = 10;
        num_eep_pages;
        cali_rows = 5000;
        cali_cols = 2;
        cali_col_data = 2;
        cali_load;
        cali_load_t;
        
        eep_load; 

        cali_name_max_len =16;

        cali_to_eep_success = 0;

        dlim = ',';
        dlim_match = strcat('[\s]*,[\s]*');
        
        cali_header_match;

        eep_fpath = '';
        
        cal_ruler = 0;
        
        delta = 0.001; 
        
        device_name_eeprom = '';
        device_name_cal = '';

    end

    methods (Access = private)

        function [err, page_map] = check_eeprom_valid(app, data)

            header = [ 82	37	104	67	68];
            header_len = length(header);

            page_map= containers.Map('KeyType','uint32','ValueType','uint16'); % eeprom data index to page map
            err = 0;
            if(size (data, 2) ~= app.eep_len)
                err = 1;
                return

            end

            for i = 1: size(data,1)

                for j = 1:size(data,2)

                    if(j <= header_len)

                        if(header(j)~= data(i, j))

                            fprintf("Header mismatch at (%d, %d)\r\n",i, j);
                            err = 2;

                        end

                    elseif (j == app.eep_idx_pagenum_start) || (j == (app.eep_idx_pagenum_start+1))

                        page_num_idx =  app.eep_idx_pagenum_end + (j - app.eep_idx_pagenum_start);

                        if(data(i, j) ~= data(i, page_num_idx) )

                            fprintf("Page number mismatch (%d, %d)\r\n",i, j);

                            if(err < 4)

                                err = err +4;
                            end
                        end

                        if j == (app.eep_idx_pagenum_start+1)

                            page_num = uint16((data(i,j)))*256 +  uint16(data(i,(j-1)));


                            if(page_num > app.eep_pagenum_max)

                                fprintf("Page number greater than max at (%d, %d)\r\n",i, j);
                                if(err < 16)

                                    err = err +16;
                                end

                            elseif(isKey(page_map, page_num))

                                fprintf("Page number duplicate at (%d, %d)\r\n",i, j);
                                if(err < 32)

                                    err = err +32;
                                end

                            else
                                page_map(page_num) = i;


                            end


                        end

                    end


                end


            end

        end


        function eep_pages = calib_to_eep_data(app,data)

            data_s = single(data);

            num_eep_pages = ceil((size(data_s, 1)*4) / app.eep_page_len);
            eep_pages = uint8(zeros(num_eep_pages, app.eep_page_len));
            eep_page_pos = 1;
            eep_page_num = 1;
            for i = 1: size(data_s, 1)


                eep_pages(eep_page_num, eep_page_pos:(eep_page_pos+3)) = typecast(data_s(i),'uint8');

                eep_page_pos = eep_page_pos+4;

                if(eep_page_pos == (app.eep_page_len +1))
                    eep_page_pos = 1;

                    eep_page_num = eep_page_num+1;

                end


            end
        end

        function calib_data = eep_data_to_calib(app, eep_data)
            % expects just the page without header or footer


            eep_data = uint8(eep_data);

            if (size(eep_data, 1) >= app.num_eep_pages) && (size(eep_data, 2) == app.eep_page_len)
                calib_data = single(zeros(app.cali_rows ,1));

                eep_page_pos = 1;
                eep_page_num = 1;

                for i = 1:app.cali_rows


                    calib_data(i) = typecast( eep_data(eep_page_num,eep_page_pos: (eep_page_pos+3)),'single');

                    eep_page_pos = eep_page_pos+4;

                    if(eep_page_pos == (app.eep_page_len +1))
                        eep_page_pos = 1;

                        eep_page_num = eep_page_num+1;

                    end


                end

            else

                fprintf("eep_data to calib wrong length expected %d by %d, got %d by %d \r\n",app.num_eep_pages, app.eep_page_len, size(eep_data, 1),size(eep_data, 2));
            end


        end

        function [error, dev_cali_load] = parse_eeprom_data(app, eep_full, page_map)


            dev_cali_load = app.cali_load_t;

            if(size(eep_full, 1) == length (page_map)) && (size(eep_full, 2) >= app.eep_len) &&  (size(eep_full, 1) >= app.num_eep_pages)


                eep_cali_data = uint8(zeros(app.num_eep_pages, app.eep_page_len));
                idx_page_start = app.eep_idx_data_start;
                idx_page_end = app.eep_idx_data_start + app.eep_page_len -1;

                for i = 0: (size(eep_cali_data, 1) -1)

                    eep_data_idx = app.eep_page_cali_data+i;

                    if(isKey(page_map,eep_data_idx))
                        eep_page_idx = page_map(eep_data_idx);
                        eep_cali_data(i+1, :) = eep_full(eep_page_idx, idx_page_start:idx_page_end);


                    end


                end
                cali_header = zeros(1, app.eep_page_len);

                if(isKey(app.eep_page_map,app.eep_page_cali_head))

                    eep_page_idx =   app.eep_page_map(app.eep_page_cali_head);
                    cali_header = eep_full(eep_page_idx, idx_page_start:idx_page_end);

                end

                error = 0;
                
                dev_cali_load.head_chk_sum = cali_header(1); 
                dev_cali_load.head_chk_sum_act = checksum_u8(cali_header, 2, -1); 
                if(dev_cali_load.head_chk_sum == dev_cali_load.head_chk_sum_act)

                    fprintf("parse_eeprom_data: Header checksum ok\r\n")

                else

                    fprintf("parse_eeprom_data: Header checksum FAIL\r\n")
                    error = 0x02;
                end

                dev_cali_load.cali_chk_sum = cali_header(2); 
                dev_cali_load.cali_chk_sum_act = checksum_eep_cali_pages(app, eep_cali_data);
                if(dev_cali_load.cali_chk_sum == dev_cali_load.cali_chk_sum_act)

                    fprintf("parse_eeprom_data: Calibration checksum ok\r\n")

                else

                    fprintf("parse_eeprom_data: Calibration checksum FAIL\r\n")
                    error = error + 0x04;
                end


                dev_cali_load.data = eep_data_to_calib(app, eep_cali_data);
                dev_cali_load.cali_id = typecast(cali_header(5:8), 'uint32');
                dev_cali_load.dev_id = typecast(cali_header(9:12), 'uint32');
                dev_cali_load.dev_name = char(cali_header(13: (13+ (app.cali_name_max_len-1))));
            else
                fprintf("parse_eeprom_data: error wrong eeprom data size\r\n")

                error = 0x01;

            end


        end

        function Update_cal_table(app, data,num)
            row_idxs(:,1) = single(1: app.cali_rows);

            
            if (size(data, 1) == app.cali_rows)


                if(num ==0)
                    col_names = {'Row', 'Cal points'};
                    tab_data  = [row_idxs, data(:,1)];
                    app.UITable_eep.Data = tab_data ;
                    app.UITable_eep.ColumnName = col_names;
                elseif(num ==1)
                    tab_data  = [row_idxs, data(:,1), data(:, app.cali_col_data)];
                    col_names = {'Row', 'Cal col 1','Cal points Col'};
                    app.UITable_cali.Data = tab_data ;
                    app.UITable_cali.ColumnName = col_names;
                end

            end

        end
        
        function Add_line_to_log(app, line)
            
            app.InfoText_log.Value = [app.InfoText_log.Value; line]; 
            
        end
        
        function chk_sum = checksum_eep_cali_pages(app, eep_cali_data)
            temp_vec(:,1) = reshape(eep_cali_data', numel(eep_cali_data),1);
                
            cali_length = app.cali_rows * 4; 
            
            check_vec = temp_vec(1:cali_length );
            
            chk_sum = checksum_u8(check_vec, 1, -1); 
        end
        
        function Clear_copy_verify(app)
            
            app.Lamp_copy.Color = [1 0 0]; 
            app.Lamp_verify.Color = [1 0 0]; 
            app.cali_to_eep_success = 0; 
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.eep_idx_pagenum_end = app.eep_len - 1; %

            token_id = '([0-9]+)';%(?:0[xX])?([0-9a-fA-F]+)'
            token_name = '(([A-Za-z0-9-]+))';
            token_datetime = '([0-9:/ ]+)';
            match_pre_cal_id = 'Calibration ID:\s?';
            match_pre_dev_id = 'Device ID:\s?';
            match_pre_dev_name = 'Name:\s?';
            match_pre_date = 'Created:';


            app.cali_header_match  =strcat(match_pre_cal_id,token_id,app.dlim_match,match_pre_dev_id,token_id,app.dlim_match,match_pre_dev_name,token_name,app.dlim_match,match_pre_date,token_datetime);

            app.cali_load_t.dev_id = uint32(0);
            app.cali_load_t.cali_id = uint32(0);
            app.cali_load_t.dev_name = 'Not loaded';
            app.cali_load_t.created = 'unknown';
            app.cali_load_t.data = zeros(1 ,app.cali_cols);
            app.cali_load_t.loaded = 0;
            app.cali_load_t.cali_chk_sum = uint8(0); 
            app.cali_load_t.head_chk_sum = uint8(0); 
            app.cali_load_t.cali_chk_sum_act = uint8(0); 
            app.cali_load_t.head_chk_sum_act = uint8(0); 

            app.cali_load = app.cali_load_t(1);

            app.eep_page_map = containers.Map('KeyType','uint32','ValueType','uint16');

            app.num_eep_pages = ceil((app.cali_rows*4) / app.eep_page_len);
            
            app.cal_ruler = (0:app.delta: (app.delta *(app.cali_rows-1)))';
            
            app.InfoText_log.Value = app.version; 
            
        end

        % Button pushed function: LoadEEPROMfileButton
        function LoadEEPROMfileButtonPushed(app, event)
            [fname, path] = uigetfile('*.csv','Select EEPROM file','C:\Occuity\EEPROM\eeprom_data_downloaded.csv');

            app.eep_fpath = strcat(path, fname);

            if isequal(fname,0) && isequal(path,0)
                fprintf('No file selected. Finishing now.\n');
                return;
            end

            eeprom_data = readmatrix(app.eep_fpath,'OutputType', 'uint8');


            [err,page_map] = check_eeprom_valid(app, eeprom_data);
            

            info_strings = cell(8,1);
            info_strings{1} = fname;

            if(err == 0)
                lamp_colour = [0 1 0];
                app.eep_data = eeprom_data;
                app.eep_page_map = page_map;
                app.eep_load_success = 1;

                [error,dev_cali_load] = parse_eeprom_data(app, eeprom_data, page_map);
                app.device_name_eeprom = regexprep(dev_cali_load.dev_name, '[\x00]*$', '');

                info_strings{2} = sprintf('Loaded %d EEPROM pages', length(page_map));
                info_strings{3} = sprintf('Calibration ID: %4u\r\n', dev_cali_load.cali_id);
                info_strings{4} = ' '; %sprintf('Device ID:      %8u \r\n', dev_cali_load.dev_id);
                info_strings{5} = sprintf('Device Name: %s \r\n', dev_cali_load.dev_name);
                info_strings{6} = ' ';
                check_str = 'Pass';
                
                if bitand( error, 0x02) > 0 
                    lamp_colour = [1 1 0];
                    check_str = 'FAIL';
                end
                
                info_strings{7} = sprintf('Cali Header CheckSum : 0x%02X %s \r\n', dev_cali_load.head_chk_sum_act, check_str);
                
                check_str = 'Pass';
                
                if bitand( error, 0x04) > 0 
                    lamp_colour = [1 1 0];
                    check_str = 'FAIL';
                end
                
                info_strings{8} = sprintf('Cali Data CheckSum : 0x%02X %s \r\n', dev_cali_load.cali_chk_sum_act, check_str);

                Update_cal_table(app,dev_cali_load.data, 0);
                app.Add_line_to_log(sprintf('Successfully loaded EEPROM file: %s',app.eep_fpath)); 
                
                ax = app.UIAxes_cali; 
                
                app.eep_load = dev_cali_load; 
                
                app.Lamp_eep.Color = lamp_colour; 
                
                Clear_copy_verify(app); 
            else
                app.Lamp_eep.Color = [1 0 0];
                app.eep_load_success = 0;
                info_strings{2} = sprintf('FAILed to load code: 0x%04X', err);
                app.Add_line_to_log(sprintf('FAILed to load EEPROM file: %s',app.eep_fpath)); 
            end


            app.InfoText_eep.Value = info_strings;

        end

        % Button pushed function: LoadCalibrationfileButton
        function LoadCalibrationfileButtonPushed(app, event)
            [fname, path] = uigetfile('*.csv');

            fpath = strcat(path, fname);

            if isequal(fname,0) && isequal(path,0)
                fprintf('No file selected. Finishing now.\n');
                return;
            end

            temp_data = readmatrix(fpath, 'OutputType', 'single');

            if(size(temp_data, 1) >=  app.cali_rows) && (size(temp_data, 2) >= app.cali_cols)

                fid = fopen(fpath , 'r');

                tline = fgetl(fid);

                fclose(fid);

                [mat, tok] = regexp(tline, app.cali_header_match , 'match', 'tokens');

                app.cali_load = app.cali_load_t;

                app.cali_load.data =  temp_data(1:app.cali_rows,1:app.cali_cols);

                Update_cal_table(app, app.cali_load.data, 1);


                lamp_colour = [0 1 0];

                if ~isempty(tok)
                    if length(tok{1}) == 4

                        app.cali_load.cali_id =  uint32(str2num(tok{1}{1}));%hex2dec(tok{1}{1}));
                        app.cali_load.dev_id =  uint32(str2num(tok{1}{2})); %hex2dec(tok{1}{2}));
                        app.cali_load.dev_name = tok{1}{3};
                        app.cali_load.created = tok{1}{4};
                    end
                end
                
                if strcmp(app.cali_load.dev_name, app.cali_load_t.dev_name)...
                        || (app.cali_load.cali_id == 0)
                    app.Add_line_to_log('Warning: Cal file load failed to load device name or id'); 
                    lamp_colour = [1 1 0];

                end
                app.device_name_cal = app.cali_load.dev_name;

                info_strings = cell(8,1);
                info_strings{1} = fname;
                info_strings{2} = sprintf('Loaded: %4d Col %d \r\n', size(app.cali_load.data,1),size(app.cali_load.data,2));
                info_strings{3} = sprintf('Calibration ID: %4u\r\n', app.cali_load.cali_id);
                info_strings{4} = ' ';
                info_strings{5} = sprintf('Device Name: %s \r\n', app.cali_load.dev_name);
                info_strings{6} = sprintf('Created:  %s\r\n', app.cali_load.created);
                info_strings{7}  = ' ';
                
                app.cali_load.cali_chk_sum_act = checksum_eep_cali_pages(app, calib_to_eep_data(app,app.cali_load.data(1:app.cali_rows, app.cali_col_data)));
                info_strings{8} = sprintf('Cali Data CheckSum : 0x%02X \r\n', app.cali_load.cali_chk_sum_act);
                app.InfoText_cali.Value = info_strings;

                app.cali_load.loaded =1;
                app.Lamp_cali.Color = lamp_colour;
                
                app.Add_line_to_log(sprintf('Successfully loaded Calibration file: %s',fpath)); 
                
                Clear_copy_verify(app); 
                
            else 
                
                app.Add_line_to_log(sprintf('FAILed to load Calibration file: %s',fpath)); 

            end

        end

        % Button pushed function: CalibrationtoEEPROMcopyButton
        function CalibrationtoEEPROMcopyButtonPushed(app, event)
            app.Lamp_copy.Color = [1 1 0];
            app.cali_to_eep_success = 0;
            if(app.cali_load.loaded ==1 ) && (app.eep_load_success == 1)
                
                if ~strcmp(app.device_name_cal, app.device_name_eeprom)
                    answer = questdlg('WARNING: Device name in EEPROM does not match that in calibration. Would you like to continue?', ...
                        'Warning', 'Yes', 'No', 'No');
                    % Handle response
                    if ~strcmp(answer, 'Yes')
                        return;
                    end
                end

                cali_data_pages =  calib_to_eep_data(app, app.cali_load.data(1:app.cali_rows, app.cali_col_data));

                cali_header = zeros(1, app.eep_page_len);

                
                % cali_header(1) = header checksum
                cali_header(2) = checksum_u8(cali_data_pages, 1, -1);
                % cali_header(3) = d2
                % cali_header(4) = d3
                cali_header(5:8) = typecast(app.cali_load.cali_id, 'uint8');
                cali_header(9:12) = typecast(app.cali_load.dev_id, 'uint8');

                name_len = length(app.cali_load.dev_name);

                if name_len > app.cali_name_max_len

                    name_len = app.cali_name_max_len;

                end

                cali_header(13: (13+ (name_len-1))) = app.cali_load.dev_name(1:name_len);

                cali_header(1) = checksum_u8(cali_header, 1, -1);

                idx_page_start = app.eep_idx_data_start;
                idx_page_end = app.eep_idx_data_start + app.eep_page_len -1;

                if(isKey(app.eep_page_map,app.eep_page_cali_head))

                    eep_page_idx =   app.eep_page_map(app.eep_page_cali_head);
                    app.eep_data(eep_page_idx, idx_page_start:idx_page_end) = cali_header;

                end
                app.cali_to_eep_success = 1;
                for i = 0: (size(cali_data_pages, 1) -1)

                    eep_data_idx = app.eep_page_cali_data+i;

                    if(isKey(app.eep_page_map,eep_data_idx))

                        eep_page_idx =   app.eep_page_map(eep_data_idx);
                        app.eep_data(eep_page_idx, idx_page_start:idx_page_end) = cali_data_pages(i+1, :);


                    else

                        fprintf('EEPROM page %d not found \r\n', eep_data_idx);
                        app.cali_to_eep_success = 0;
                    end

                end

                if(app.cali_to_eep_success ==1)

                    app.Lamp_copy.Color = [0 1 0];


                    info_strings = cell(8,1);
                    info_strings{1} = 'UPDATED eeprom data';

                    [error,dev_cali_load] = parse_eeprom_data(app, app.eep_data, app.eep_page_map);
                    info_strings{2} = sprintf('Loaded %d EEPROM pages', length(app.eep_page_map));


                    info_strings{3} = sprintf('Calibration ID: %4u\r\n', dev_cali_load.cali_id);
                    info_strings{4} = ' '; %sprintf('Device ID:      %8u \r\n', dev_cali_load.dev_id);
                    info_strings{5} = sprintf('Device Name: %s \r\n', dev_cali_load.dev_name);
                    
                    info_strings{6} = ' ';
                    check_str = 'Pass';
                    
                    if bitand( error, 0x02) > 0 
                        
                        check_str = 'FAIL';
                    end
                    
                    info_strings{7} = sprintf('Cali Header CheckSum : 0x%02X %s \r\n', dev_cali_load.head_chk_sum_act, check_str);
                    
                    check_str = 'Pass';
                    
                    if bitand( error, 0x04) > 0 
                        
                        check_str = 'FAIL';
                    end
                    
                    info_strings{8} = sprintf('Cali Data CheckSum : 0x%02X %s \r\n', dev_cali_load.cali_chk_sum_act, check_str);


                    Update_cal_table(app,dev_cali_load.data, 0);

                    app.Lamp_eep.Color = [0 0 1];


                    app.InfoText_eep.Value = info_strings;
                    app.eep_load = dev_cali_load; 
                    if error > 0
                        
                        app.cali_to_eep_success = 0; 
                        
                    end


                end 
                
                if app.cali_to_eep_success ==0
                    app.Lamp_copy = [1 0 0];

                    app.Lamp_eep = [1 0 0];
                    app.eep_load_success  = 0; 

                end


            end


        end

        % Button pushed function: VerifyButton
        function VerifyButtonPushed(app, event)
            
            if(app.cali_load.loaded ==1 ) && (app.eep_load_success == 1)
            
            [err,page_map] = check_eeprom_valid(app, app.eep_data);

            verifed = 1; 
            app.Lamp_verify.Color =[1 0 0];
            if(err == 0)
                app.Add_line_to_log('EEPROM format:  OK');
                
                check_str = 'FAIL';
                if  (length(app.eep_page_map) == length(page_map))
                    
                    check_str = 'OK';
                    app.eep_load_success = 1;
                    
                else 
                    verifed = 0; 
                    
                end
                
                app.Add_line_to_log(sprintf('EEPROM page length check: %s', check_str));

                [error,dev_cali_load] = parse_eeprom_data(app, app.eep_data,app.eep_page_map);
                
                if error == 0
                    
                    app.Add_line_to_log('EEPROM Parse:  OK');
                    check_str = 'FAIL';
                    
                    if(dev_cali_load.cali_chk_sum_act ==  app.cali_load.cali_chk_sum_act)
                        
                        check_str = 'OK';
                    else 
                        verifed = 0; 
                    
                    end
                    
                    app.Add_line_to_log(sprintf('EEPROM checksum to Cali checksum check: %s', check_str));
                    
                    
                    check_str = 'OK';
                    try
                        
                        for i = 1: app.cali_rows
                        
                            if(dev_cali_load.data(i)~= app.cali_load.data(i, app.cali_col_data))
                                check_str = 'FAIL';
                                verifed = 0; 
                                break; 
                            end
                        
                        
                        end
                        
                    catch ME
                        check_str = 'FAIL';
                        verifed = 0; 
                    end
                    
                    app.Add_line_to_log(sprintf('EEPROM data to Cali data compare : %s', check_str));
                    
                    check_str = 'FAIL';
                    
                    if(dev_cali_load.cali_id==  app.cali_load.cali_id)
                            %&&(dev_cali_load.dev_id==  app.cali_load.dev_id)
                        
                        check_str = 'OK';
                    else 
                        verifed = 0; 
                    
                    end
                    
                    app.Add_line_to_log(sprintf('EEPROM IDs compare : %s', check_str));


                else
                    app.Add_line_to_log('EEPROM Parse:  FAIL');
                    verifed = 0; 
                    
                end


            end
            
            if verifed == 1
                app.Add_line_to_log(sprintf('EEPROM VERIFY PASS'));
                app.Lamp_verify.Color =[0 1 0];
                if(app.cali_to_eep_success ==1)
                    [fname, path] = uiputfile('*.csv', 'Save Modifed EEPROM file', 'C:\Occuity\EEPROM\eeprom_data_for_upload.csv');
    
                    fpath = strcat(path, fname);
        
                    if isequal(fname,0) && isequal(path,0)
                        fprintf('No file selected. Finishing now.\n');
                        return;
                    end


                    try
                        writematrix(app.eep_data, fpath ,'Delimiter',app.dlim);
                        app.Add_line_to_log(sprintf('EEPROM data saved to: %s', fpath));
                    catch ME
                        
                        err_str = strcat ('Save error:',ME.message);
                        app.Lamp_verify.Color =[1 1 0];
                        app.Add_line_to_log(err_str); 
                        
                    end
                    
                end 
                
            else 
                
                app.Add_line_to_log(sprintf('EEPROM VERIFY FAIL'));
            end
            

            end
            
            scroll(app.InfoText_log,'bottom');
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.UIFigure.Visible = 'off';
            
            eeprom_cali_load();
            
            close(app.UIFigure);
        end

        % Button pushed function: RefreshgraphsButton
        function RefreshgraphsButtonPushed(app, event)
            
            
            app.UIAxes_cali.NextPlot = 'replace';
            plot (app.UIAxes_cali,[0 5], [0 5],':k', 'DisplayName','ref')
            app.UIAxes_cali.NextPlot = 'add';
            
            app.UIAxes_delta.NextPlot = 'replace';
            plot (app.UIAxes_delta,[0 5], [0 0],':k', 'DisplayName','ref')
            app.UIAxes_delta.NextPlot = 'add';
            
            if (app.cali_load.loaded ==1 ) 
                
                plot(app.UIAxes_cali, (app.cali_load.data(:, 1)), (app.cali_load.data(:, app.cali_col_data)), '-r', 'DisplayName','Cal File');
                app.UIAxes_cali.NextPlot = 'add';
                
                cali_delta = app.cali_load.data(:, 1) - app.cali_load.data(:, app.cali_col_data); 
                delta_x  = app.cali_load.data((1:end), 1); 
                
                plot(app.UIAxes_delta, delta_x , (cali_delta), '-r', 'DisplayName','Cal File');
                app.UIAxes_delta.NextPlot = 'add';
            end
                
            if app.eep_load_success ==1 
                
                plot(app.UIAxes_cali, app.cal_ruler, app.eep_load.data(:, 1), '-b','DisplayName', 'EEPROM' );
            
                delta_x  = app.cal_ruler(1:end);
                cali_delta = app.cal_ruler(1:end) -app.eep_load.data(:, 1); 
                
                plot(app.UIAxes_delta, delta_x , (cali_delta), '-b','DisplayName', 'EEPROM' );
            end
            
            axis(app.UIAxes_cali, [0 5 0 5])
            grid(app.UIAxes_cali,"minor"); 
            legend(app.UIAxes_cali,'Location' , 'southeast');
            
            legend(app.UIAxes_delta, 'Location' , 'southeast');
            grid(app.UIAxes_delta,"minor"); 
            axis(app.UIAxes_delta, 'tight');
            
        end

        % Value changed function: InfoText_log
        function InfoText_logValueChanged(app, event)
            
            scroll(app.InfoText_log,'bottom');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LoadEEPROMfileButton = uibutton(app.UIFigure, 'push');
            app.LoadEEPROMfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadEEPROMfileButtonPushed, true);
            app.LoadEEPROMfileButton.Text = {'Load EEPROM file'; ''};
            app.LoadCalibrationfileButton = uibutton(app.UIFigure, 'push');
            app.LoadCalibrationfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCalibrationfileButtonPushed, true);
            app.LoadCalibrationfileButton.Text = {'Load Calibration file'; ''};
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LoadedTab = uitab(app.TabGroup);
            app.LoadedTab.Title = 'Loaded ';
            app.EEPROMFilePanel = uipanel(app.LoadedTab);
            app.EEPROMFilePanel.Title = 'EEPROM File';
            app.UITable_eep = uitable(app.EEPROMFilePanel);
            app.UITable_eep.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable_eep.RowName = {};
            app.InfoTextArea_2Label = uilabel(app.EEPROMFilePanel);
            app.InfoTextArea_2Label.Text = 'Info';
            app.InfoText_eep = uitextarea(app.EEPROMFilePanel);
            app.InfoText_eep.Value = {'Not Loaded'};
            app.CalibrationfilePanel = uipanel(app.LoadedTab);
            app.CalibrationfilePanel.Title = 'Calibration file';
            app.InfoTextAreaLabel = uilabel(app.CalibrationfilePanel);
            app.InfoTextAreaLabel.Text = 'Info';
            app.InfoText_cali = uitextarea(app.CalibrationfilePanel);
            app.InfoText_cali.Editable = 'off';
            app.InfoText_cali.Value = {'Not Loaded'};
            app.UITable_cali = uitable(app.CalibrationfilePanel);
            app.UITable_cali.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable_cali.RowName = {};
            app.GraphTab = uitab(app.TabGroup);
            app.GraphTab.Title = 'Graph';
            app.UIAxes_cali = uiaxes(app.GraphTab);
            title(app.UIAxes_cali, 'Calibration vs Reference')
            xlabel(app.UIAxes_cali, 'Ref')
            ylabel(app.UIAxes_cali, 'Cali')
            zlabel(app.UIAxes_cali, 'Z')
            app.UIAxes_delta = uiaxes(app.GraphTab);
            title(app.UIAxes_delta, 'Delta over Reference Range')
            xlabel(app.UIAxes_delta, 'Ref')
            ylabel(app.UIAxes_delta, 'Delta')
            zlabel(app.UIAxes_delta, 'Z')
            app.RefreshgraphsButton = uibutton(app.GraphTab, 'push');
            app.RefreshgraphsButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshgraphsButtonPushed, true);
            app.RefreshgraphsButton.Text = 'Refresh graphs';
            app.CalibrationtoEEPROMcopyButton = uibutton(app.UIFigure, 'push');
            app.CalibrationtoEEPROMcopyButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrationtoEEPROMcopyButtonPushed, true);
            app.CalibrationtoEEPROMcopyButton.Text = {'Calibration to EEPROM'; ' copy'; ''};
            app.VerifyButton = uibutton(app.UIFigure, 'push');
            app.VerifyButton.ButtonPushedFcn = createCallbackFcn(app, @VerifyButtonPushed, true);
            app.VerifyButton.Text = {'Verify'; ''};
            app.LogLabel = uilabel(app.UIFigure);
            app.LogLabel.Text = 'Log';
            app.InfoText_log = uitextarea(app.UIFigure);
            app.InfoText_log.ValueChangedFcn = createCallbackFcn(app, @InfoText_logValueChanged, true);
            app.InfoText_log.Editable = 'off';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Lamp_eep = uilamp(app.UIFigure);
            app.Lamp_2Label = uilabel(app.UIFigure);
            app.Lamp_2Label.Text = '';
            app.Lamp_cali = uilamp(app.UIFigure);
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.Lamp_copy = uilamp(app.UIFigure);
            app.Lamp_4Label = uilabel(app.UIFigure);
            app.Lamp_4Label.Text = '';
            app.Lamp_verify = uilamp(app.UIFigure);
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = eeprom_cali_load

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end